classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        DrawletterButton             matlab.ui.control.Button
        TrainednetworksListBoxLabel  matlab.ui.control.Label
        TrainednetworksListBox       matlab.ui.control.ListBox
        ClassificationLabel          matlab.ui.control.Label
        ALabel                       matlab.ui.control.Label
        PathtoimageEditFieldLabel    matlab.ui.control.Label
        PathtoimageEditField         matlab.ui.control.EditField
        NewnetworkPanel              matlab.ui.container.Panel
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        TrainingfunctionKnobLabel    matlab.ui.control.Label
        TrainingfunctionKnob         matlab.ui.control.DiscreteKnob
        TrainButton                  matlab.ui.control.Button
        ActivationfunctionKnobLabel  matlab.ui.control.Label
        ActivationfunctionKnob       matlab.ui.control.DiscreteKnob
        DatasetEditFieldLabel        matlab.ui.control.Label
        DatasetEditField             matlab.ui.control.EditField
        LayersEditFieldLabel         matlab.ui.control.Label
        LayersEditField              matlab.ui.control.EditField
        TrainEditField_2Label        matlab.ui.control.Label
        TrainEditField               matlab.ui.control.NumericEditField
        ValidationEditField_2Label   matlab.ui.control.Label
        ValidationEditField          matlab.ui.control.NumericEditField
        TestEditField_2Label         matlab.ui.control.Label
        TestEditField                matlab.ui.control.NumericEditField
        ClassifyButton               matlab.ui.control.Button
        GeneratenetworkraporttofileEditFieldLabel  matlab.ui.control.Label
        GeneratenetworkraporttofileEditField  matlab.ui.control.EditField
        GenerateButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentNetwork % Description
        currentTrainingRecord % Description
    end
    
    methods (Access = private)
        
        function tab = drawPointsToLabel(app, points)
            p = uint8(points * 28)
            tab(1:28, 1:28) = 0;
            for i = 1:length(p)
                tab(28 - p(i, 2), p(i, 1)) = 1;
            end
        end
        
        function refreshNetworksList(app)
            listFiles = dir('networks\*.mat');
            fileList = {listFiles.name};
            app.TrainednetworksListBox.Items = fileList;
        end
        
        function getFirstNetwork(app)
            listFiles = dir('networks\*.mat');
            fileList = {listFiles.name};
            if ~isempty(fileList)
                loadNetwork(app, char(fileList(1)))
            end
        end
        
        function loadNetwork(app, name)
            app.currentNetwork = load(strcat('networks\', name));
            app.currentNetwork = app.currentNetwork.netToSave;
            
            checkNetworkSelected(app);
        end
        
        
        function checkNetworkSelected(app)
            if isempty(app.currentNetwork)
                app.DrawletterButton.Enable = false;
                app.ClassifyButton.Enable = false;
                app.GenerateButton.Enable = false;
            else
                app.DrawletterButton.Enable = true;
                app.ClassifyButton.Enable = true;
                app.GenerateButton.Enable = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        set(app.UIAxes,'Color','black');
        refreshNetworksList(app);
        checkNetworkSelected(app);
        getFirstNetwork(app);

        end

        % Button pushed function: DrawletterButton
        function DrawletterButtonPushed(app, event)
            cla(app.UIAxes);
            a = drawfreehand(app.UIAxes);
            a.LineWidth = 5;
            a.Color = 'white';
            a.InteractionsAllowed = 'none';
            a.FaceAlpha = 0;
            a.FaceSelectable = false;
            a.Closed = 0;
            
            z = drawPointsToLabel(app, a.Position);
            imwrite(z, 'drawn.png');
            app.ALabel.Text = letter_functions.classifyImage(app.currentNetwork, z); 
       
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)

            dataSet = app.DatasetEditField.Value
            [inputs, targets] = letter_functions.readDataSet(dataSet);
            
            app.currentNetwork = letter_functions.createNet(app.LayersEditField.Value);
            app.currentNetwork = letter_functions.selectActivation(app.currentNetwork, app.ActivationfunctionKnob.Value);
            app.currentNetwork = letter_functions.selectTraining(app.currentNetwork, app.TrainingfunctionKnob.Value);
            app.currentNetwork = letter_functions.segmentData(app.currentNetwork, app.TrainEditField.Value, app.ValidationEditField.Value, app.TestEditField.Value);
            [app.currentNetwork, currentTrainingRecord] = letter_functions.trainFunction(app.currentNetwork, inputs, targets);
            netToSave = app.currentNetwork;
            trToSave = currentTrainingRecord;
            save(strcat('networks\', app.NameEditField.Value), "netToSave");
            refreshNetworksList(app);
        end

        % Value changed function: TrainednetworksListBox
        function TrainednetworksListBoxValueChanged(app, event)
            value = app.TrainednetworksListBox.Value;
            loadNetwork(app, value);
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            app.ALabel.Text = letter_functions.classifyImageFromFilename(app.currentNetwork, app.PathtoimageEditField.Value);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

            fileName = app.GeneratenetworkraporttofileEditField.Value;
            
            [pathstr, name] = fileparts(app.TrainednetworksListBox.Value);
            fid = fopen(strcat('raports\',fileName), 'w');
           
            
            fprintf(fid, '%6s %12s\r\n', strcat('Raport: ', name));
            fprintf(fid, '\n');
            fprintf(fid, '\n');
            
            fprintf(fid, '%6s %12s\r\n', 'Accuracy for set_1: ');
            acc = letter_functions.getAccuracyForSet(app.currentNetwork, 'set_1');
            fprintf(fid, '%6s %12s\r\n', num2str(acc,'%.2f'));
            fprintf(fid, '\n');
            
            fprintf(fid, '%6s %12s\r\n', 'Accuracy for set_2: ');
            acc = letter_functions.getAccuracyForSet(app.currentNetwork, 'set_2');
            fprintf(fid, '%6s %12s\r\n', num2str(acc,'%.2f'));
            fprintf(fid, '\n');
            
            fprintf(fid, '%6s %12s\r\n', 'Accuracy for set_3: ');
            acc = letter_functions.getAccuracyForSet(app.currentNetwork, 'set_3');
            fprintf(fid, '%6s %12s\r\n', num2str(acc,'%.2f'));
            fprintf(fid, '\n');
            
            fprintf(fid, '%6s %12s\r\n', 'Accuracy for set_custom: ');
            acc = letter_functions.getAccuracyForSet(app.currentNetwork, 'set_custom');
            fprintf(fid, '%6s %12s\r\n', num2str(acc,'%.2f'));
            fprintf(fid, '\n');
            
            fprintf(fid, '%6s %12s\r\n', 'Accuracy for set_all: ');
            acc = letter_functions.getAccuracyForSet(app.currentNetwork, 'set_all');
            fprintf(fid, '%6s %12s\r\n', num2str(acc,'%.2f'));
            fprintf(fid, '\n');      
        
            
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Letter')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DrawletterButton = uibutton(app.UIFigure, 'push');
            app.DrawletterButton.ButtonPushedFcn = createCallbackFcn(app, @DrawletterButtonPushed, true);
            app.DrawletterButton.Text = {'Draw letter'; ''};
            app.TrainednetworksListBoxLabel = uilabel(app.UIFigure);
            app.TrainednetworksListBoxLabel.Text = 'Trained networks';
            app.TrainednetworksListBox = uilistbox(app.UIFigure);
            app.TrainednetworksListBox.ValueChangedFcn = createCallbackFcn(app, @TrainednetworksListBoxValueChanged, true);
            app.ClassificationLabel = uilabel(app.UIFigure);
            app.ClassificationLabel.Text = 'Classification:';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.PathtoimageEditFieldLabel = uilabel(app.UIFigure);
            app.PathtoimageEditFieldLabel.Text = 'Path to image:';
            app.PathtoimageEditField = uieditfield(app.UIFigure, 'text');
            app.NewnetworkPanel = uipanel(app.UIFigure);
            app.NewnetworkPanel.Title = 'New network';
            app.NameEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.NameEditField.Value = 'network1';
            app.TrainingfunctionKnobLabel = uilabel(app.NewnetworkPanel);
            app.TrainingfunctionKnobLabel.Text = 'Training function';
            app.TrainingfunctionKnob = uiknob(app.NewnetworkPanel, 'discrete');
            app.TrainingfunctionKnob.Items = {'trainlm', 'trainbfg', 'trainbr', 'traingda', 'trainr'};
            app.TrainingfunctionKnob.Value = 'trainlm';
            app.TrainButton = uibutton(app.NewnetworkPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ActivationfunctionKnobLabel = uilabel(app.NewnetworkPanel);
            app.ActivationfunctionKnobLabel.Text = 'Activation function';
            app.ActivationfunctionKnob = uiknob(app.NewnetworkPanel, 'discrete');
            app.ActivationfunctionKnob.Items = {'tansig', 'purelin', 'hardlim', 'poslin', 'logsig'};
            app.ActivationfunctionKnob.Value = 'tansig';
            app.DatasetEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.DatasetEditFieldLabel.Text = 'Data set';
            app.DatasetEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.DatasetEditField.Value = 'set_2';
            app.LayersEditFieldLabel = uilabel(app.NewnetworkPanel);
            app.LayersEditFieldLabel.Text = 'Layers';
            app.LayersEditField = uieditfield(app.NewnetworkPanel, 'text');
            app.LayersEditField.Value = '10';
            app.TrainEditField_2Label = uilabel(app.NewnetworkPanel);
            app.TrainEditField_2Label.Text = 'Train%';
            app.TrainEditField = uieditfield(app.NewnetworkPanel, 'numeric');
            app.TrainEditField.Limits = [0 100];
            app.TrainEditField.Value = 70;
            app.ValidationEditField_2Label = uilabel(app.NewnetworkPanel);
            app.ValidationEditField_2Label.Text = 'Validation%';
            app.ValidationEditField = uieditfield(app.NewnetworkPanel, 'numeric');
            app.ValidationEditField.Limits = [0 100];
            app.ValidationEditField.Value = 15;
            app.TestEditField_2Label = uilabel(app.NewnetworkPanel);
            app.TestEditField_2Label.Text = 'Test%';
            app.TestEditField = uieditfield(app.NewnetworkPanel, 'numeric');
            app.TestEditField.Limits = [0 100];
            app.TestEditField.Value = 15;
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.GeneratenetworkraporttofileEditFieldLabel = uilabel(app.UIFigure);
            app.GeneratenetworkraporttofileEditFieldLabel.Text = 'Generate network raport to file';
            app.GeneratenetworkraporttofileEditField = uieditfield(app.UIFigure, 'text');
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end